classdef TowerHanoiGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        SelectMoveDropDownLabel    matlab.ui.control.Label
        SelectMoveDropDown         matlab.ui.control.DropDown
        ResetGameButton            matlab.ui.control.Button
        SizeofTowerEditFieldLabel  matlab.ui.control.Label
        SizeofTowerEditField       matlab.ui.control.NumericEditField
        ChangeSizeButton           matlab.ui.control.Button
        UndolastmoveButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        DefaultRingSize = 5;                      % Default number of rings
        CurrentSize;                              % Current Size of the Game Tower
                                     
        Colors = {'b','g','r','c','m','y','k','w'};
        
        DefaultTower;
        CurrentTower;
        PreviousTower;
        tower;
        
%         Peg1;
%         Peg2;
%         Peg3;


    end
    
    methods (Access = private)
        
        function CreateNewTowerPegs(app)
            app.CurrentSize = app.SizeofTowerEditField.Value;
            N = app.CurrentSize; 
            
            app.tower = struct('Peg1',{{}},'Peg2',{{}},'Peg3',{{}});
            
            for i = N:-1:1
                
                app.tower.Peg1{i}  = struct('Size', N-i+1, 'Color', app.Colors{N-i+1});
                
                
            end
            app.tower.Peg2 = {};
            app.tower.Peg3 = {};
            app.CurrentTower = app.tower;
            h = app.UIAxes; 
            display_tower_GUI(h,app.tower);
        end
        
        function InitializeGame(app)
            
            app.SizeofTowerEditField.Value = app.DefaultRingSize;
            app.CreateNewTowerPegs();
            app.DefaultTower = app.tower; 
            app.CurrentTower = app.tower;
            app.UndolastmoveButton.Enable = 'off';
        end
        
        function ResetGame(app)
            
            app.CreateNewTowerPegs();
            app.UndolastmoveButton.Enable = 'off';
            
        end
        
        function ChangeTowerPegs(app)
            
            h = app.UIAxes;
            newTower = app.CurrentTower;
            display_tower_GUI(h,newTower);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InitializeGame();
            
            
        end

        % Button pushed function: ResetGameButton
        function ResetGameButtonPushed(app, event)
            app.ResetGame();
            
        end

        % Value changed function: SelectMoveDropDown
        function SelectMoveDropDownValueChanged(app, event)
            value = app.SelectMoveDropDown.Value;
%             fieldname1 = ['Peg',num2str(value(1))];
%             fieldname2 = ['Peg',num2str(value(5))];
%             FromPeg    = app.tower.(fieldname1);
%             ToPeg      = app.tower.(fieldname2);

            if(value ~= "Move")
                Initial_Peg = str2num(value(1));
                Final_Peg   = str2num(value(5));
                
                [towerstate,ret_code] = move(app.CurrentTower,Initial_Peg,Final_Peg);
                if(ret_code == 0)  %fail status
                    
                    app.SelectMoveDropDown.Value = "Move";
                    uialert(app.UIFigure,'That move is illegal!','Illegal Move');
                  
                    return;
                    
                else                %pass status
                    app.PreviousTower = app.CurrentTower;
                    app.UndolastmoveButton.Enable = 'on';
                    app.CurrentTower = towerstate;
                    app.ChangeTowerPegs();

                    if(numel(app.CurrentTower.Peg3) == app.CurrentSize && Final_Peg ~= 1)     %As mentioned in Part 1 Peg3 is final state
                        uialert(app.UIFigure,'Congratulations you successfully completed the Tower of Hanoi game','Congratulations!','Icon','success');
                        app.SelectMoveDropDown.Value = "Move";
                    end
                    app.SelectMoveDropDown.Value = "Move";
                    return;
                end
                
            end
            
             
        end

        % Button pushed function: ChangeSizeButton
        function ChangeSizeButtonPushed(app, event)
            
            app.CurrentSize = app.SizeofTowerEditField.Value;
            app.CreateNewTowerPegs();
            app.SelectMoveDropDown.Value ="Move";
        end

        % Button pushed function: UndolastmoveButton
        function UndolastmoveButtonPushed(app, event)
            app.CurrentTower = app.PreviousTower;
            app.ChangeTowerPegs();
            app.UndolastmoveButton.Enable = 'off';
            app.SelectMoveDropDown.Value  = "Move";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Tower of Hanoi Game')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SelectMoveDropDownLabel = uilabel(app.UIFigure);
            app.SelectMoveDropDownLabel.Text = 'Select Move';
            app.SelectMoveDropDown = uidropdown(app.UIFigure);
            app.SelectMoveDropDown.Items = {'Move', '1-->2', '2-->1', '1-->3', '3-->1', '2-->3', '3-->2'};
            app.SelectMoveDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectMoveDropDownValueChanged, true);
            app.SelectMoveDropDown.Value = 'Move';
            app.ResetGameButton = uibutton(app.UIFigure, 'push');
            app.ResetGameButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGameButtonPushed, true);
            app.ResetGameButton.Text = 'Reset Game';
            app.SizeofTowerEditFieldLabel = uilabel(app.UIFigure);
            app.SizeofTowerEditFieldLabel.Text = 'Size of Tower';
            app.SizeofTowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.SizeofTowerEditField.Limits = [1 8];
            app.SizeofTowerEditField.Value = 5;
            app.ChangeSizeButton = uibutton(app.UIFigure, 'push');
            app.ChangeSizeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeSizeButtonPushed, true);
            app.ChangeSizeButton.Text = 'Change Size';
            app.UndolastmoveButton = uibutton(app.UIFigure, 'push');
            app.UndolastmoveButton.ButtonPushedFcn = createCallbackFcn(app, @UndolastmoveButtonPushed, true);
            app.UndolastmoveButton.Text = 'Undo last move ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TowerHanoiGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end